classdef StimSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateFullStimulationButton  matlab.ui.control.Button
        ParadigmdesignPanel            matlab.ui.container.Panel
        StimulationTypeDropDown        matlab.ui.control.DropDown
        StimulationTypeDropDownLabel   matlab.ui.control.Label
        but_AddStimType                matlab.ui.control.Button
        txt_StimType2Add               matlab.ui.control.EditField
        but_RemoveStimType             matlab.ui.control.Button
        lbl_Status                     matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        but_LoadCalibCurve             matlab.ui.control.Button
        txt_CalibCurvePath             matlab.ui.control.EditField
        calibpathEditFieldLabel        matlab.ui.control.Label
        axes_CalibCurve                matlab.ui.control.UIAxes
        SoundbatterywavfilesPanel      matlab.ui.container.Panel
        lbl_NumStimLoaded              matlab.ui.control.Label
        but_LoadStimFolder             matlab.ui.control.Button
        txt_SoundBatteryPath           matlab.ui.control.EditField
        pathEditFieldLabel             matlab.ui.control.Label
        FrequencyTuninggeneratesPanel  matlab.ui.container.Panel
        txt_FTDuration                 matlab.ui.control.EditField
        durationsLabel                 matlab.ui.control.Label
        but_Generate                   matlab.ui.control.Button
        txt_dBStep                     matlab.ui.control.EditField
        dBStepEditFieldLabel           matlab.ui.control.Label
        txt_dBSPLEnd                   matlab.ui.control.EditField
        dBSPLEndEditFieldLabel         matlab.ui.control.Label
        txt_dBSPLStart                 matlab.ui.control.EditField
        dBSPLStartEditFieldLabel       matlab.ui.control.Label
        LinearfreqgenerationLabel      matlab.ui.control.Label
        txt_FreqStep                   matlab.ui.control.EditField
        FreqStepEditFieldLabel         matlab.ui.control.Label
        txt_FreqEnd                    matlab.ui.control.EditField
        FreqEndEditFieldLabel          matlab.ui.control.Label
        txt_FreqStart                  matlab.ui.control.EditField
        FreqStartkHzEditFieldLabel     matlab.ui.control.Label
        StimulationParametersPanel     matlab.ui.container.Panel
        txt_Fs                         matlab.ui.control.NumericEditField
        fsHzEditFieldLabel             matlab.ui.control.Label
        txt_Ntrials                    matlab.ui.control.EditField
        NtrialsLabel                   matlab.ui.control.Label
        txt_RampDuration               matlab.ui.control.EditField
        rampdursEditFieldLabel         matlab.ui.control.Label
        txt_IStimI                     matlab.ui.control.EditField
        interstimintsEditFieldLabel    matlab.ui.control.Label
        txt_PostTime                   matlab.ui.control.EditField
        posttimesEditFieldLabel        matlab.ui.control.Label
        txt_PreTime                    matlab.ui.control.EditField
        pretimesEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        % datapaths
        datapath_calibcurve = ''; % path to the calibration curve
        datapath_soundbattery = ''; % path to the data where sounds to be presented from file are
        
        % stimulation properties
        ramp_duration = 0;
        fs = 0;
        
        % wavfiles
        wavfiles = []; % wav files in sound battery directory
        wavstims = {};
        
        % freq_tuning stimuli
        ft_freqs = [];
        ft_levels = [];
        ft_stims = {}; % freq, levels (2D cell array)
        
        % general stimulation properties...
        Ntrials = 0;
        ISI = -1;
        pre_time = 0;
        post_time = 0;
        
        stim_protocol = []; % large stimulation protocol stimulus (concatenating all
                                % random stimuli to be presented.. whatever
                                % they are -> wav files or freq tuning)...
    end
    
    methods (Access = private)
        
        function ret = ramp_stimulus_onoff( app, x )
            
            if ( app.ramp_duration <= 0 ) 
                ret = x; return; end;
            
            % make the ramp...
            samples_rampon = ceil( app.ramp_duration .* app.fs );
            ramp_on = linspace( 0, 1, samples_rampon );
            ramp = [ ramp_on, ones( 1, numel(x) - 2 .* numel(ramp_on) ), fliplr( ramp_on ) ];
            
            ret = x .* ramp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: but_LoadStimFolder
        function but_LoadStimFolderPushed(app, event)
            
            clc;
            
            %% loading the folder with the sound battery
            
            % check whether there's a path variable in the folder...
            if ( isfile( 'path_soundbattery.mat' ) )
                load path_soundbattery.mat;
                path_soundbattery = uigetdir( path_soundbattery, 'Select wav folder' );
            else
                path_soundbattery = uigetdir( '', 'Select wav folder' );
            end
            
            if ( path_soundbattery == 0 ) path_soundbattery = ''; end % just in case
            % save in the app properties..
            app.datapath_soundbattery = path_soundbattery;
            % save in matlab variable outside
            save path_soundbattery path_soundbattery;
            
            %% getting the wav file names...
            % get the wavfiles
            app.wavfiles = dir( [ app.datapath_soundbattery, '\', '*.wav' ] );
            
            % update all status
            app.lbl_NumStimLoaded.Text = sprintf( 'Loaded %d wav files', numel( app.wavfiles ) );
            app.txt_SoundBatteryPath.Value = path_soundbattery;
            app.lbl_Status.Text = 'Loaded Stims for sound battery';
            
        end

        % Button pushed function: but_Generate
        function but_GenerateButtonPushed(app, event)
            
            % Generates the frequency tuning stimuli.
            
            % get the frequencies...
            app.ft_freqs = [ str2num( app.txt_FreqStart.Value ) : str2num( app.txt_FreqStep.Value ) : str2num( app.txt_FreqEnd.Value ) ];
            % get the levels
            app.ft_levels = [ str2num( app.txt_dBSPLStart.Value ) : str2num( app.txt_dBStep.Value ) : str2num( app.txt_dBSPLEnd.Value ) ];
            
            % generate all the stims at various freqs.. given duration...
            % I'll add the amplitude part later, when I figure out how to
            % do the calibration...
            
            % some properties of the tones...
            L = str2num( app.txt_FTDuration.Value );
            app.fs = app.txt_Fs.Value;
            t = [ 0 : 1/app.fs : L ];
            app.ramp_duration = str2num( app.txt_RampDuration.Value );
            
            % generate per freq now...
            for m = 1 : numel( app.ft_freqs )
                
                freqnowKHz = app.ft_freqs( m );
                % generate a cosine with duration L, and specific freq
                tone_now = cos( 2 * pi * (freqnowKHz*1000) .* t );
                % check if multiply the stim by a ramp...
                if ( app.ramp_duration > 0 )
                   tone_now = app.ramp_stimulus_onoff( tone_now ); end
               
                for mm = 1 : numel( app.ft_levels )
                    
                    %tone_now_cal = app.calibrate_tone_2dB( IMPLEMENT );
                    TONE_NOW_CAL = tone_now; % this needs to be removed
                    
                    app.ft_stims{m, mm} = TONE_NOW_CAL;
                end
            end
            
            2+2;
        end

        % Button pushed function: but_RemoveStimType
        function but_RemoveStimTypePushed(app, event)
            
            % Removes the seleted Stimulation type from the drop_down
            % optionss..
            itemsNow = app.StimulationTypeDropDown.Items;
            item_selected = app.StimulationTypeDropDown.Value;
            
            if ( numel( itemsNow ) == 1 ) return; end;
            
            % find the idx of the selected, remove it...
            idxnow = find( string(item_selected) == string( itemsNow ) );
            % and remove it as easily as possible
            % check if idx is not on the edge.. then it's easy
            if ( idxnow ~= 1 & idxnow < numel( itemsNow ) )
                itemsNow = itemsNow( [ 1 : idxnow - 1, idxnow + 1 : numel(itemsNow) ] );
            elseif idxnow == 1
                itemsNow = itemsNow( 2 : end );
            else
                itemsNow = itemsNow( 1 : end-1 );
            end
            
            app.StimulationTypeDropDown.Items = itemsNow;
            
            2 + 2;
        end

        % Button pushed function: but_AddStimType
        function but_AddStimTypeButtonPushed(app, event)
            
            % Adds a stimulation type (in case that you want it to be
            % 'DelayTuning' and/or 'SyllTracking' (examples) instead of
            % 'SoundBattery', which is pretty damn unspecific...
            itemsNow = app.StimulationTypeDropDown.Items;
            item2add = app.txt_StimType2Add.Value;
            itemsNow{end + 1} = item2add;
            app.StimulationTypeDropDown.Items = itemsNow;
        end

        % Button pushed function: GenerateFullStimulationButton
        function GenerateFullStimulationButtonPushed(app, event)
            
            % This generates the indexing paradigm for presenting
            % stimuli... Ultimately, from here come the protocol files that
            % will let me know what stimulus was presented....
            
            app.pre_time = str2num( app.txt_PreTime.Value );
            app.post_time = str2num( app.txt_PostTime.Value );
            app.ISI = str2num( app.txt_IStimI.Value );
            app.fs = app.txt_Fs.Value;
            app.Ntrials = str2num( app.txt_Ntrials.Value );
            
            nsamples_pre = ceil( app.fs * app.pre_time );
            nsamples_post = ceil( app.fs * app.post_time );
            nsamples_ISI = ceil( app.fs * app.ISI );
            
            % first, let's generate for the frequency tuning (generated
            % sounds inside of the app itself)
            protocolnow = app.StimulationTypeDropDown.Value;
            
            if ( strcmp( protocolnow, 'FreqTuning' ) )
                
                % ..............frequency tuning..........
                
                Nstims = prod( size( app.ft_stims ) ); % frequency x levels
                randstim_idx = [];
                for kk = 1 : app.Ntrials
                    randstim_idx = [ randstim_idx, randperm( Nstims ) ];
                end
                
                % yet another layer of random
                randstim_idx = randstim_idx( randperm(numel(randstim_idx)) );
                
                [freqId, levelId] = ind2sub( size(app.ft_stims), randstim_idx );
                Nstims = numel(freqId);
                
                % concatenate the frequency tuning stimuli to present...
                % init some things.. start from the second stim...
                % the full size of the stim.. pre-allocate so that it
                % doesn't take so much time to generate...
                samples_triggers = nan( 1, Nstims );
                app.stim_protocol = zeros( 1, ...
                    ( numel( app.ft_stims{1,1} ) + nsamples_ISI + nsamples_post + nsamples_pre ) * Nstims - 1 + ...
                      numel( app.ft_stims{1,1} ) + nsamples_pre + nsamples_post );
                
                % adding up the first stimulus
                samples_triggers(1) = 1;
                x = app.ft_stims{ freqId(1), levelId(1) };
                app.stim_protocol( 1 : nsamples_pre + numel( app.ft_stims{1,1} ) + nsamples_post ) = ...
                    [ zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ]; % the first stimulus
                
                end_of_first = nsamples_pre + numel( app.ft_stims{1,1} ) + nsamples_post; % end of first stimulus
                nsamples_stim = numel(x); % stimuli have always the same length in freq tuning...
                
                for kk = 2 : numel( freqId )
                    
                    x = app.ft_stims{ freqId(kk), levelId(kk) };
                    x_ISIprepost = [ zeros(1, nsamples_ISI), zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ];
                    
                    sample_start = ( end_of_first ) + ...
                                   ( kk - 2 ) * (nsamples_ISI + nsamples_stim + nsamples_pre + nsamples_post) + 1;
                    sample_end = sample_start + (nsamples_ISI + nsamples_stim + nsamples_pre + nsamples_post) - 1;
                    
                    % add to the stimlulus ensemble
                    app.stim_protocol( sample_start : sample_end ) = x_ISIprepost;
                    
                    % get me where the triggers are happening...
                    samples_triggers(kk) = sample_start + nsamples_ISI; % it's where the stim (with pre and post) start...
                end

            else
            
                % Sound battery protocol.. This here is when stimuli come
                % from file in a folder... Load the stimuli in the files,
                % then check if they have the same fs as what we have
                % here (and fix).. just concatenate them again as we did
                % with the freq tuning...
                
                wav_path = app.datapath_soundbattery;
                Nstims = numel( app.wavfiles ) .* app.Ntrials;
                
                % load all the stimuli
                allstim_duration = 0;
                for kk = 1 : numel( app.wavfiles )
                    fname = app.wavfiles(kk).name;
                    % load the file
                    [x, fswav] = audioread( [app.datapath_soundbattery, '\', fname] ); x = x';
                    % should it be resampled?
                    if ( app.fs ~= fswav )
                        x = resample( x, app.fs, fswav ); end % resample
                    app.wavstims{kk} = x;
                    stim_samples{kk} = numel(x);
                    allstim_duration = allstim_duration + stim_samples{kk};
                end
                
                % now that we have all the stimuli, let's reorganize them
                randstim_idx = [];
                for m = 1 : app.Ntrials
                    randstim_idx = [ randstim_idx, ...
                                     randperm( numel( app.wavstims ) ) ];
                end

                % how long does a stimulus block take (a block is a
                % presentation of all N wav files)
                block_duration = allstim_duration + ...
                                 ( nsamples_ISI + nsamples_pre + nsamples_post ) * numel( app.wavstims );
                                 
                % pre-allocate for the full protocol super-stimulus variable
                app.stim_protocol = zeros( 1, block_duration * app.Ntrials );
                
                % consturct the super-stimulus of the protocol
                last_sample = 0;
                samples_triggers = nan( 1, Nstims );
                
                for kk = 1 : Nstims
                    
                    x = app.wavstims{ randstim_idx(kk) };
                    x_ISIprepost = [ zeros(1, nsamples_ISI), zeros(1, nsamples_pre), x, zeros(1, nsamples_post) ];
                    
                    sample_start = last_sample + 1;
                    sample_end = sample_start + numel( x_ISIprepost ) - 1;
                    
                    % add the current wav to the super-stimulus... (with
                    % all included)
                    app.stim_protocol( sample_start : sample_end ) = x_ISIprepost;
                    % save where the trigger is happening...
                    samples_triggers(kk) = sample_start;
                    
                    % update last sample to use with next wav
                    last_sample = sample_end;
                end

            end
            
            % now, the trigger needs to be added to the signal....
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulationParametersPanel = uipanel(app.UIFigure);
            app.StimulationParametersPanel.Title = 'Stimulation Parameters';
            app.pretimesEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.pretimesEditFieldLabel.Text = 'pre-time (s)';
            app.txt_PreTime = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_PreTime.Tag = 'txt_PreTime';
            app.txt_PreTime.Value = '0.05';
            app.posttimesEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.posttimesEditFieldLabel.Text = 'post-time (s)';
            app.txt_PostTime = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_PostTime.Tag = 'txt_PostTime';
            app.txt_PostTime.Value = '0.25';
            app.interstimintsEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.interstimintsEditFieldLabel.Text = 'inter stim int (s)';
            app.txt_IStimI = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_IStimI.Tag = 'txt_ISI';
            app.txt_IStimI.Value = '0.25';
            app.rampdursEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.rampdursEditFieldLabel.Text = 'ramp dur. (s)';
            app.txt_RampDuration = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_RampDuration.Tag = 'txt_PreTime';
            app.txt_RampDuration.Value = '0.5e-3';
            app.NtrialsLabel = uilabel(app.StimulationParametersPanel);
            app.NtrialsLabel.Text = 'Ntrials';
            app.txt_Ntrials = uieditfield(app.StimulationParametersPanel, 'text');
            app.txt_Ntrials.Tag = 'txt_PreTime';
            app.txt_Ntrials.Value = '50';
            app.fsHzEditFieldLabel = uilabel(app.StimulationParametersPanel);
            app.fsHzEditFieldLabel.Text = 'fs (Hz)';
            app.txt_Fs = uieditfield(app.StimulationParametersPanel, 'numeric');
            app.txt_Fs.Value = 384000;
            app.FrequencyTuninggeneratesPanel = uipanel(app.UIFigure);
            app.FrequencyTuninggeneratesPanel.Title = 'Frequency Tuning';
            app.FrequencyTuninggeneratesPanel.Tag = 'PanelFreqTuning';
            app.FreqStartkHzEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqStartkHzEditFieldLabel.Text = 'Freq Start (kHz)';
            app.txt_FreqStart = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqStart.Tag = 'txt_FreqStart';
            app.txt_FreqStart.Value = '5';
            app.FreqEndEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqEndEditFieldLabel.Text = 'Freq End';
            app.txt_FreqEnd = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqEnd.Tag = 'txt_FreqEnd';
            app.txt_FreqEnd.Value = '90';
            app.FreqStepEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.FreqStepEditFieldLabel.Text = 'Freq Step';
            app.txt_FreqStep = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FreqStep.Tag = 'txt_FreqStep';
            app.txt_FreqStep.Value = '5';
            app.LinearfreqgenerationLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.LinearfreqgenerationLabel.Text = 'Linear freq generation';
            app.dBSPLStartEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBSPLStartEditFieldLabel.Text = 'dBSPL Start';
            app.txt_dBSPLStart = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBSPLStart.Tag = 'txt_dbSPLStart';
            app.txt_dBSPLStart.Value = '5';
            app.dBSPLEndEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBSPLEndEditFieldLabel.Text = 'dBSPL End';
            app.txt_dBSPLEnd = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBSPLEnd.Tag = 'txt_dbSPLEnd';
            app.txt_dBSPLEnd.Value = '90';
            app.dBStepEditFieldLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.dBStepEditFieldLabel.Text = 'dB Step';
            app.txt_dBStep = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_dBStep.Tag = 'txt_dBStep';
            app.txt_dBStep.Value = '15';
            app.but_Generate = uibutton(app.FrequencyTuninggeneratesPanel, 'push');
            app.but_Generate.ButtonPushedFcn = createCallbackFcn(app, @but_GenerateButtonPushed, true);
            app.but_Generate.Text = 'Generate tones';
            app.durationsLabel = uilabel(app.FrequencyTuninggeneratesPanel);
            app.durationsLabel.Text = 'duration (s)';
            app.txt_FTDuration = uieditfield(app.FrequencyTuninggeneratesPanel, 'text');
            app.txt_FTDuration.Tag = 'txt_dBStep';
            app.txt_FTDuration.Value = '10e-3';
            app.SoundbatterywavfilesPanel = uipanel(app.UIFigure);
            app.SoundbatterywavfilesPanel.Title = 'Sound battery (wav files)';
            app.pathEditFieldLabel = uilabel(app.SoundbatterywavfilesPanel);
            app.pathEditFieldLabel.Text = 'path';
            app.txt_SoundBatteryPath = uieditfield(app.SoundbatterywavfilesPanel, 'text');
            app.txt_SoundBatteryPath.Tag = 'txt_WavFolder';
            app.but_LoadStimFolder = uibutton(app.SoundbatterywavfilesPanel, 'push');
            app.but_LoadStimFolder.ButtonPushedFcn = createCallbackFcn(app, @but_LoadStimFolderPushed, true);
            app.but_LoadStimFolder.Tag = 'but_LoadStimBattery';
            app.but_LoadStimFolder.Text = 'LoadFolder';
            app.lbl_NumStimLoaded = uilabel(app.SoundbatterywavfilesPanel);
            app.lbl_NumStimLoaded.Tag = 'lbl_NSoundsLoaded';
            app.lbl_NumStimLoaded.Text = 'Number of loaded stims';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.axes_CalibCurve = uiaxes(app.Panel);
            title(app.axes_CalibCurve, 'CalibrationCurve')
            xlabel(app.axes_CalibCurve, 'X')
            ylabel(app.axes_CalibCurve, 'Y')
            zlabel(app.axes_CalibCurve, 'Z')
            app.calibpathEditFieldLabel = uilabel(app.Panel);
            app.calibpathEditFieldLabel.Text = 'calib. path';
            app.txt_CalibCurvePath = uieditfield(app.Panel, 'text');
            app.txt_CalibCurvePath.Tag = 'txt_PathCalibCurve';
            app.but_LoadCalibCurve = uibutton(app.Panel, 'push');
            app.but_LoadCalibCurve.Tag = 'but_LoadCalibCurve';
            app.but_LoadCalibCurve.Text = 'LoadCalibCurve';
            app.lbl_Status = uilabel(app.UIFigure);
            app.lbl_Status.Tag = 'lbl_Status';
            app.lbl_Status.Text = 'Waiting to load / generate stimuli...';
            app.ParadigmdesignPanel = uipanel(app.UIFigure);
            app.ParadigmdesignPanel.Title = 'Paradigm design';
            app.but_RemoveStimType = uibutton(app.ParadigmdesignPanel, 'push');
            app.but_RemoveStimType.ButtonPushedFcn = createCallbackFcn(app, @but_RemoveStimTypePushed, true);
            app.but_RemoveStimType.Text = 'RemoveCurrType';
            app.txt_StimType2Add = uieditfield(app.ParadigmdesignPanel, 'text');
            app.txt_StimType2Add.Value = 'DTuning';
            app.but_AddStimType = uibutton(app.ParadigmdesignPanel, 'push');
            app.but_AddStimType.ButtonPushedFcn = createCallbackFcn(app, @but_AddStimTypeButtonPushed, true);
            app.but_AddStimType.Text = 'AddType';
            app.StimulationTypeDropDownLabel = uilabel(app.ParadigmdesignPanel);
            app.StimulationTypeDropDownLabel.Text = 'Stimulation Type';
            app.StimulationTypeDropDown = uidropdown(app.ParadigmdesignPanel);
            app.StimulationTypeDropDown.Items = {'FreqTuning', 'SoundBattery'};
            app.StimulationTypeDropDown.Value = 'FreqTuning';
            app.GenerateFullStimulationButton = uibutton(app.UIFigure, 'push');
            app.GenerateFullStimulationButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFullStimulationButtonPushed, true);
            app.GenerateFullStimulationButton.Text = 'GenerateFullStimulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StimSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end